classdef weather < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        HTML           matlab.ui.control.HTML
        Image          matlab.ui.control.Image
        PredictButton  matlab.ui.control.Button
        UITable        matlab.ui.control.Table
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        forecastData % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            response = getData;
            app.forecastData = parseForecast(response.list);
            forecastTable = struct2table(app.forecastData);
            app.UITable.Data = forecastTable;
            plot(app.UIAxes,forecastTable.date,forecastTable.temperature)
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            X = (1:36)';
            res1 = getData();
            f = parseForecast(res1.list);
            f = struct2table(f);
            y = f.temperature;
            y2 = linReg(X);
            plot(app.UIAxes,f.date,y,f.date,y2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Weather forecast')
            xlabel(app.UIAxes, 'date')
            ylabel(app.UIAxes, 'temp')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'date'; 'temperature'};
            app.UITable.RowName = {};
            app.PredictButton = uibutton(app.GridLayout, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'OpenWeather.svg');
            app.HTML = uihtml(app.GridLayout);
            app.HTML.HTMLSource = '<br/><a href="http://samples.openweathermap.org/data/2.5/forecast?q=Muenchen,DE&appid=b1b15e88fa797225412429c1c50c122a1" target="_blank"> Muenchen, DE</a>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = weather

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end